classdef ExaPra < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        pruebaButton                   matlab.ui.control.Button
        pruebaEditField                matlab.ui.control.EditField
        pruebaEditFieldLabel           matlab.ui.control.Label
        VargasSorianoFlorArletteLabel  matlab.ui.control.Label
        ElegirSealPanel                matlab.ui.container.Panel
        Suma8sealesButton              matlab.ui.control.Button
        SealSenoidalDropDown           matlab.ui.control.DropDown
        SealSenoidalDropDownLabel      matlab.ui.control.Label
        SealdeaudioPanel               matlab.ui.container.Panel
        ReproducirButton               matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        Switch                         matlab.ui.control.Switch
        SwitchLabel                    matlab.ui.control.Label
        AbrirButton                    matlab.ui.control.Button
        GrabarButton                   matlab.ui.control.Button
        FiltrosPanel                   matlab.ui.container.Panel
        filtraaa                       matlab.ui.control.DropDown
        DominioFrecuenciaButton        matlab.ui.control.Button
        DominioTiempoButton            matlab.ui.control.Button
        FiltrosDropDown                matlab.ui.control.DropDown
        FiltrosDropDownLabel           matlab.ui.control.Label
        TransformadaRapidadeFourierPanel  matlab.ui.container.Panel
        FFTButton                      matlab.ui.control.Button
        VentanearPanel                 matlab.ui.container.Panel
        VentanasDropDown               matlab.ui.control.DropDown
        VentanasDropDownLabel          matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        suma
        senal 
        Y
        frecuencia1
        grabar = "Off"
        recordData
        Fs
        senalfiltrada
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Suma8sealesButton
        function Suma8sealesButtonPushed(app, event)
          t= 0:seconds(1/25000):seconds(length(app.suma)./25000);
          t = t(1:end-1);
          t=(0:0.001:1)';
          seno1 = sin(2*pi*50*t);
          seno2 = sin(2*pi*100*t);
          seno3 = sin(2*pi*200*t);
          seno4 = sin(2*pi*400*t);
          seno5 = sin(2*pi*800*t);
          seno6 = sin(2*pi*1600*t);
          seno7 = sin(2*pi*3200*t);
          seno8 = sin(2*pi*6400*t);
          suma = seno1 +seno2+seno3+seno4+seno5+seno6+seno7+seno8;
          app.suma=suma;
          plot(app.UIAxes,t,app.suma);
          title(app.UIAxes,"Suma de las señales")
        end

        % Value changed function: SealSenoidalDropDown
        function SealSenoidalDropDownValueChanged(app, event)
            value = app.SealSenoidalDropDown.Value;
            t=0:1/64000:1;
            if (value == "50Hz")
                 seno1 = sin(2*pi*50*t);
                 plot(app.UIAxes,t,seno1);
                 title(app.UIAxes,"Seno en frecuencia 50HZ");
            end
            if (value == "100Hz")
                seno2 = sin(2*pi*100*t);
                 plot(app.UIAxes,t,seno2);
                 title(app.UIAxes,"Seno en frecuencia 100HZ");
                 
            end
            if (value == "200Hz")
                seno3 = sin(2*pi*200*t);
                 plot(app.UIAxes,t,seno3);
                 title(app.UIAxes,"Seno en frecuencia 200HZ");
                
            end
            if (value == "400Hz")
                seno4 = sin(2*pi*400*t);
                 plot(app.UIAxes,t,seno4);
                 title(app.UIAxes,"Seno en frecuencia 400HZ");
            end
            if (value == "800Hz")
                seno5 = sin(2*pi*800*t);
                 plot(app.UIAxes,t,seno5);
                 title(app.UIAxes,"Seno en frecuencia 800HZ");
                 
            end
            if (value == "1600Hz")
                seno6 = sin(2*pi*1600*t);
                 plot(app.UIAxes,t,seno6);
                 title(app.UIAxes,"Seno en frecuencia 1600HZ");
                
            end
            if (value == "3200Hz")
                seno7 = sin(2*pi*3200*t);
                 plot(app.UIAxes,t,seno7);
                 title(app.UIAxes,"Seno en frecuencia 3200HZ");
                 
            end
            if (value == "6400Hz")
                seno8 = sin(2*pi*6400*t);
                 plot(app.UIAxes,t,seno8);
                 title(app.UIAxes,"Seno en frecuencia 6400HZ");
            end

        end

        % Value changed function: VentanasDropDown
        function VentanasDropDownValueChanged(app, event)
            value = app.VentanasDropDown.Value;
            if(app.grabar == "Off")
                 L= length(app.suma);
                 %L = 6401
                  
                if (value == "Rectangular");
                     Ven = rectwin(L);
                    plot(app.UIAxes2,Ven);
                    title(app.UIAxes2,"Ventana Rectangular");
                    app.senal=app.suma.*Ven;
                    plot(app.UIAxes,app.senal);
                    title(app.UIAxes,"Señal Ventaneada");
                    
                end
                if (value == "Triangular");
                     Ven = triang(L);
                    plot(app.UIAxes2,Ven);
                    title(app.UIAxes2,"Ventana Triangular");
                    app.senal=app.suma.*Ven;
                    plot(app.UIAxes,app.senal);
                    title(app.UIAxes,"Señal Ventaneada");
                end
                if (value == "Flat Top")
                    Ven = flattopwin(L);
                    plot(app.UIAxes2,Ven);
                    title(app.UIAxes2,"Ventana Flat Top");
                    app.senal=app.suma.*Ven;
                    plot(app.UIAxes,app.senal);
                    title(app.UIAxes,"Señal Ventaneada");
                end
                if (value == "Hanning")
                    Ven = hann(L);
                    plot(app.UIAxes2,Ven);
                    title(app.UIAxes2,"Ventana Hanning");
                    app.senal=app.suma.*Ven;
                    plot(app.UIAxes,app.senal);
                    title(app.UIAxes,"Señal Ventaneada");
                end
                if (value == "Hamming")
                    Ven = hamming(L,'symmetric');
                    plot(app.UIAxes2,Ven);
                    title(app.UIAxes2,"Ventana Hamming");
                    app.senal=app.suma.*Ven;
                    plot(app.UIAxes,app.senal);
                    title(app.UIAxes,"Señal Ventaneada");
    
                    %app.senal=[app.suma(:,1).*Ven app.suma(:,2).*Ven];
                end
            else
                L= length(app.recordData);
                  
                if (value == "Rectangular")
                     Ven = rectwin(L);
                    plot(app.UIAxes2,Ven);
                    title(app.UIAxes2,"Ventana Rectangular");
                    app.senal=app.recordData.*Ven;
                    plot(app.UIAxes,app.senal);
                    title(app.UIAxes,"Señal Ventaneada");
                    
                end
                if (value == "Triangular")
                     Ven = triang(L);
                    plot(app.UIAxes2,Ven);
                    title(app.UIAxes2,"Ventana Triangular");
                    app.senal=app.recordData.*Ven;
                    plot(app.UIAxes,app.senal);
                    title(app.UIAxes,"Señal Ventaneada");
                end
                if (value == "Flat Top")
                    Ven = flattopwin(L);
                    plot(app.UIAxes2,Ven);
                    title(app.UIAxes2,"Ventana Flat Top");
                    app.senal=app.recordData.*Ven;
                    plot(app.UIAxes,app.senal);
                    title(app.UIAxes,"Señal Ventaneada");
                end
                if (value == "Hanning")
                    Ven = hann(L);
                    plot(app.UIAxes2,Ven);
                    title(app.UIAxes2,"Ventana Hanning");
                    app.senal=app.recordData.*Ven;
                    plot(app.UIAxes,app.senal);
                    title(app.UIAxes,"Señal Ventaneada");
                end
                if (value == "Hamming")
                    Ven = hamming(L,'symmetric');
                    plot(app.UIAxes2,Ven);
                    title(app.UIAxes2,"Ventana Hamming");
                    app.senal=app.recordData.*Ven;
                    plot(app.UIAxes,app.senal);
                    title(app.UIAxes,"Señal Ventaneada");
    
                    %app.senal=[app.suma(:,1).*Ven app.suma(:,2).*Ven];
                end
            end
            
        end

        % Value changed function: FiltrosDropDown
        function FiltrosDropDownValueChanged(app, event)
            value = app.FiltrosDropDown.Value;
            %if(app.grabar == "Off")
                bandera = app.filtraaa.Value;
                app.pruebaEditField.Value= bandera
                %t = 0:1/64000:1
                %seno1 = sin(2*pi*1000*t);
                if(bandera == "2")
                    fs = 20*6400
                    %fs = meanfreq(app.senal)% Sampling frequency                    
                    T = 1/fs;             % Sampling period       
                    L = length(app.senal);   % Length of signal
                    t = (0:L-1)*T;
                    if (value == "Pasa Altas")
                         fil = highpass(app.senal,500,fs);
                         app.senalfiltrada= fil;
                        plot(app.UIAxes,t,app.senalfiltrada);
                        title(app.UIAxes,"Filtro Pasa Altas");
                    end
                    if (value == "Pasa Bajas")
                         fil = lowpass(app.senal,1000,fs);
                          app.senalfiltrada= fil;
                        plot(app.UIAxes,t,fil);
                        title(app.UIAxes,"Filtro Pasa Bajas");
                    end
                    if (value == "Pasa Banda")
                        fil = bandpass(app.senal,[500,2000],fs);
                         app.senalfiltrada= fil;
                        plot(app.UIAxes,t,fil);
                        title(app.UIAxes,"Filtro Pasa Banda");
                    end
                    if (value == "Rechaza Banda")
                        fil = bandstop(app.senal,[500,2000],fs);
                         app.senalfiltrada= fil;
                        plot(app.UIAxes,t,fil);
                        title(app.UIAxes,"Filtro Rechaza Banda");
                    end
                end
                if(bandera == "3")
                    fs = 20*6400
                    T = 1/fs;             % Sampling period       
                    L = length(app.Y);   % Length of signal
                    t = (0:L-1)*T;
                     plot(app.UIAxes2,t,app.Y);
                      title(app.UIAxes,"Señal que se filtra");
                    %fs = app.frecuencia1
                    %fs = meanfreq(app.senal)% Sampling frequency  
                    
                    if (value == "Pasa Altas");
                         fil = highpass(app.Y,500,fs);
                          app.senalfiltrada= fil;
                        plot(app.UIAxes,t,fil);
                        title(app.UIAxes,"Filtro Pasa Altas");
                    end
                    if (value == "Pasa Bajas");
                         fil = lowpass(app.Y,1000,fs);
                          app.senalfiltrada= fil;
                        plot(app.UIAxes,t,fil);
                        title(app.UIAxes,"Filtro Pasa Bajas");
                    end
                    if (value == "Pasa Banda");
                        fil = bandpass(app.Y,[500,2000],fs);
                         app.senalfiltrada= fil;
                        plot(app.UIAxes,t,fil);
                        title(app.UIAxes,"Filtro Pasa Banda");
                    end
                    if (value == "Rechaza Banda");
                        fil = bandstop(app.Y,[500,2000],fs);
                         app.senalfiltrada= fil;
                        plot(app.UIAxes,t,fil);
                        title(app.UIAxes,"Filtro Rechaza Banda");
                    end
                end
           % else
            %end
        end

        % Button pushed function: FFTButton
        function FFTButtonPushed(app, event)
          if(app.grabar == "Off")
                %t= 0:seconds(1/64000):seconds(length(y)./64000);
                %t = t(1:end-1);
                t=(0:0.00001:1)';
                %y = sin(2*pi*500*t)+2*sin(2*pi*400*t);
                y= app.senal;
                %plot(app.UIAxes,t,y);
                %%%%%%%Aplicar FTT
                YT = fft(y);
                plot(app.UIAxes2,t,YT);
                title(app.UIAxes2,"Transformada de Fourier");
                L = length(y);
                f1 = 64000 *(0:(L/2))/L;
                f2 = (-L/2:L/2-1)*(64000/L);
                app.frecuencia1 = f1;
                P2= abs(YT/L);
                P1 = P2(1:L/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                app.Y= P1;
                plot(app.UIAxes,f1,app.Y);
                title(app.UIAxes,"Transformada de Fourier(Single)");
          else
                t=(0:0.001:1)';
                audio =  app.senal;
                Fs= 44100 ;
                L = length(audio);
                NFFT = 2^nextpow2(L);
                Y = fft(audio, NFFT)/L;
                %plot(app.UIAxes2,t,Y);
                title(app.UIAxes2,"Transformada de Fourier");
                f = Fs/2*linspace(0,1,NFFT/2+1);
                plot(app.UIAxes, f,2*abs(Y(1:NFFT/2+1)));
                P2= abs(Y/L);
                P1 = P2(1:L/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                app.Y =P1;
                title(app.UIAxes,"Transformada de Fourier(Single)");

           end

        end

        % Callback function
        function verButtonPushed(app, event)
             plot(app.UIAxes2,app.suma)
        end

        % Button pushed function: GrabarButton
        function GrabarButtonPushed(app, event)
         fs = 44100;
         segs=3;
         senal_salida = audiorecorder(fs,16,1);
         msgbox("Empezando Grabacion","Grabando");
         recordblocking(senal_salida,segs);
         msgbox("Terminando GRabacion","Grabadora");
         senal_grabada = getaudiodata(senal_salida,'single');
         audiowrite(uiputfile({'*.wav'},'Guardar como'),senal_grabada,fs);

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.grabar = value;
            if(value == "On")
                app.Lamp.Color='g';
                app.GrabarButton.Visible ="on";
                app.AbrirButton.Visible="on";
                app.Suma8sealesButton.Visible ="off";
                app.ReproducirButton.Visible="on";
                app.SealSenoidalDropDown.Visible ="off";
            else
                 app.Lamp.Color='r';
                 app.GrabarButton.Visible ="off";
                 app.AbrirButton.Visible="off";
                 app.ReproducirButton.Visible="off";
                 app.Suma8sealesButton.Visible ="on";
                app.SealSenoidalDropDown.Visible ="on";
            end
        end

        % Button pushed function: DominioFrecuenciaButton
        function DominioFrecuenciaButtonPushed(app, event)
           %%%%%%%%%%%%%%%%%DOMINIO DE LA FRECUENCIA
           if(app.grabar == "Off")
                 f= 6400;
                 d = 1;
                 Fmax = f;
                 Fs = 20*Fmax;
                 Ts= 1/Fs;
                 t = 0:Ts:d;
                 x = app.suma;
                 L = length(x)
                 nFFT=2;
                 while nFFT <L
                     nFFT =nFFT*32
                 end
                 %%% Dominio de la frecuencia
                 Y1 = fft(x,nFFT)
                 PS1 = abs(Y1)
                 f = linspace(0,Fs,nFFT); %% Vector de tiempos
                 plot(app.UIAxes2,f,PS1)
                 axis(app.UIAxes2,[0 Fs/2 0 max(PS1)])
                 title(app.UIAxes2,"Frecuencia Señal original")


                 y = app.senalfiltrada;
                 Lo = length(y)
                 nFFT2=2;
                 while nFFT2 <Lo
                     nFFT2 =nFFT2*32
                 end
                 %%% Dominio de la frecuencia
                 Y12 = fft(y,nFFT2)
                 PS12 = abs(Y12)
                 f = linspace(0,Fs,nFFT2); %% Vector de tiempos
                 plot(app.UIAxes,f,PS12)
                 axis(app.UIAxes,[0 Fs/2 0 max(PS12)])
                 title(app.UIAxes,"Frecuencia Señal filtrada")
           else 
                f= 44100;
                 d = 1;
                 Fmax = f;
                 Fs = 20*Fmax;
                 Ts= 1/Fs;
                 t = 0:Ts:d;
                 x = app.recordData;
                 L = length(x)
                 nFFT=2;
                 while nFFT <L
                     nFFT =nFFT*32
                 end
                 %%% Dominio de la frecuencia
                 Y1 = fft(x,nFFT)
                 PS1 = abs(Y1)
                 f = linspace(0,Fs,nFFT); %% Vector de tiempos
                 plot(app.UIAxes2,f,PS1)
                 axis(app.UIAxes2,[0 Fs/2 0 max(PS1)])
                 title(app.UIAxes2,"Frecuencia Señal original")
            

                 y = app.senalfiltrada;
                 Lo = length(y)
                 nFFT2=2;
                 while nFFT2 <Lo
                     nFFT2 =nFFT2*32
                 end
                 %%% Dominio de la frecuencia
                 Y12 = fft(y,nFFT2)
                 PS12 = abs(Y12)
                 f2 = linspace(0,Fs,nFFT2); %% Vector de tiempos
                 plot(app.UIAxes,f2,PS12)
                 axis(app.UIAxes,[0 Fs/2 0 max(PS12)])
                 title(app.UIAxes,"Frecuencia Señal filtrada")
           end

             
        end

        % Button pushed function: DominioTiempoButton
        function DominioTiempoButtonPushed(app, event)
            bandera = app.filtraaa.Value;
             %%%%%%%%%%%%%%%%%DOMINIO DEL TIEMPO
             if(app.grabar == "Off")
              t= 0:seconds(1/6400):seconds(length(app.suma)./6400);
              t = t(1:end-1);
              t=(0:0.001:1)';

                   
              plot(app.UIAxes2,t,app.suma);
              title(app.UIAxes2,"Señal Original");
                   fs = 20*6400
                    T = 1/fs;             % Sampling period       
                    L = length(app.Y);   % Length of signal
                    ti= (0:L-1)*T;
                 if(bandera == "3")
                    plot(app.UIAxes,ti,app.senalfiltrada);
                     title(app.UIAxes,"Señal Filtrada");
                 else 
                     plot(app.UIAxes,t,app.senalfiltrada);
                     title(app.UIAxes,"Señal Filtrada");
                 end
             else
              t= 0:seconds(1/44100):seconds(length(app.recordData)./44100);
              t = t(1:end-1);
              t=(0:0.001:1)';
              plot(app.UIAxes2,app.recordData);
              title(app.UIAxes2,"Señal Original");

              plot(app.UIAxes,app.senalfiltrada);
               title(app.UIAxes,"Señal Filtrada");
             end

        end

        % Button pushed function: AbrirButton
        function AbrirButtonPushed(app, event)
           [y,Fs] = audioread(uigetfile({'*.wav'},'Abrir'));
            app.recordData = y;
            app.Fs=Fs;
            plot(app.UIAxes,y);
             title(app.UIAxes,"Grabación");
     

        end

        % Button pushed function: ReproducirButton
        function ReproducirButtonPushed(app, event)
           
        end

        % Button pushed function: pruebaButton
        function pruebaButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.VentanearPanel = uipanel(app.UIFigure);
            app.VentanearPanel.Title = 'Ventanear';
            app.VentanasDropDownLabel = uilabel(app.VentanearPanel);
            app.VentanasDropDownLabel.Text = 'Ventanas ';
            app.VentanasDropDown = uidropdown(app.VentanearPanel);
            app.VentanasDropDown.Items = {'Elegir Opción', 'Rectangular', 'Triangular', 'Flat Top', 'Hanning', 'Hamming'};
            app.VentanasDropDown.ValueChangedFcn = createCallbackFcn(app, @VentanasDropDownValueChanged, true);
            app.VentanasDropDown.Value = 'Elegir Opción';
            app.TransformadaRapidadeFourierPanel = uipanel(app.UIFigure);
            app.TransformadaRapidadeFourierPanel.Title = 'Transformada Rapida de Fourier';
            app.FFTButton = uibutton(app.TransformadaRapidadeFourierPanel, 'push');
            app.FFTButton.ButtonPushedFcn = createCallbackFcn(app, @FFTButtonPushed, true);
            app.FFTButton.Text = 'FFT';
            app.FiltrosPanel = uipanel(app.UIFigure);
            app.FiltrosPanel.Title = 'Filtros';
            app.FiltrosDropDownLabel = uilabel(app.FiltrosPanel);
            app.FiltrosDropDownLabel.Text = 'Filtros';
            app.FiltrosDropDown = uidropdown(app.FiltrosPanel);
            app.FiltrosDropDown.Items = {'Elegir opción', 'Pasa Altas', 'Pasa Bajas', 'Pasa Banda', 'Rechaza Banda'};
            app.FiltrosDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltrosDropDownValueChanged, true);
            app.FiltrosDropDown.Value = 'Elegir opción';
            app.DominioTiempoButton = uibutton(app.FiltrosPanel, 'push');
            app.DominioTiempoButton.ButtonPushedFcn = createCallbackFcn(app, @DominioTiempoButtonPushed, true);
            app.DominioTiempoButton.Text = 'Dominio Tiempo';
            app.DominioFrecuenciaButton = uibutton(app.FiltrosPanel, 'push');
            app.DominioFrecuenciaButton.ButtonPushedFcn = createCallbackFcn(app, @DominioFrecuenciaButtonPushed, true);
            app.DominioFrecuenciaButton.Text = 'Dominio Frecuencia';
            app.filtraaa = uidropdown(app.FiltrosPanel);
            app.filtraaa.Items = {'Selecciona ', 'Señal', 'Transformada'};
            app.filtraaa.ItemsData = {'1', '2', '3'};
            app.filtraaa.Value = '1';
            app.SealdeaudioPanel = uipanel(app.UIFigure);
            app.SealdeaudioPanel.Title = 'Señal de audio';
            app.GrabarButton = uibutton(app.SealdeaudioPanel, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.GrabarButton.Visible = 'off';
            app.GrabarButton.Text = 'Grabar';
            app.AbrirButton = uibutton(app.SealdeaudioPanel, 'push');
            app.AbrirButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirButtonPushed, true);
            app.AbrirButton.Visible = 'off';
            app.AbrirButton.Text = 'Abrir';
            app.SwitchLabel = uilabel(app.SealdeaudioPanel);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.SealdeaudioPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp = uilamp(app.SealdeaudioPanel);
            app.ReproducirButton = uibutton(app.SealdeaudioPanel, 'push');
            app.ReproducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButtonPushed, true);
            app.ReproducirButton.Visible = 'off';
            app.ReproducirButton.Text = 'Reproducir';
            app.ElegirSealPanel = uipanel(app.UIFigure);
            app.ElegirSealPanel.Title = 'Elegir Señal';
            app.SealSenoidalDropDownLabel = uilabel(app.ElegirSealPanel);
            app.SealSenoidalDropDownLabel.Text = 'Señal Senoidal';
            app.SealSenoidalDropDown = uidropdown(app.ElegirSealPanel);
            app.SealSenoidalDropDown.Items = {'Elegir Opción', '50Hz', '100Hz', '200Hz', '400Hz', '800Hz', '1600Hz', '3200Hz', '6400Hz'};
            app.SealSenoidalDropDown.ValueChangedFcn = createCallbackFcn(app, @SealSenoidalDropDownValueChanged, true);
            app.SealSenoidalDropDown.Value = 'Elegir Opción';
            app.Suma8sealesButton = uibutton(app.ElegirSealPanel, 'push');
            app.Suma8sealesButton.ButtonPushedFcn = createCallbackFcn(app, @Suma8sealesButtonPushed, true);
            app.Suma8sealesButton.Text = 'Suma 8 señales';
            app.VargasSorianoFlorArletteLabel = uilabel(app.UIFigure);
            app.VargasSorianoFlorArletteLabel.Text = 'Vargas Soriano Flor Arlette';
            app.pruebaEditFieldLabel = uilabel(app.UIFigure);
            app.pruebaEditFieldLabel.Text = 'prueba';
            app.pruebaEditField = uieditfield(app.UIFigure, 'text');
            app.pruebaButton = uibutton(app.UIFigure, 'push');
            app.pruebaButton.ButtonPushedFcn = createCallbackFcn(app, @pruebaButtonPushed, true);
            app.pruebaButton.Text = 'prueba';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExaPra

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end